classdef playing_with_N_and_k < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        kSliderLabel             matlab.ui.control.Label
        kSlider                  matlab.ui.control.Slider
        NSlider_2Label           matlab.ui.control.Label
        NSlider                  matlab.ui.control.Slider
        originalImgUIAxes        matlab.ui.control.UIAxes
        interpolatedImgUIAxes    matlab.ui.control.UIAxes
        WhichBitsToRemoveSwitch  matlab.ui.control.Switch
        WhichbitstoremoveLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        imgPath
        img
    end

    methods (Access = private)

        function updateImg(app)
            bits_to_reduce = 8 - app.kSlider.Value;
            N = app.NSlider.Value;

            finalImg = app.img;

            % Changing intensity resolution
            if bits_to_reduce ~= 0
                finalImg = app.changeIntensity(bits_to_reduce);
            end

            finalImage = imresize(finalImg, N);                         % Resizing
            imshow(finalImage, 'Parent', app.interpolatedImgUIAxes);    % Displaying image
        end
        
        function finalImg = changeIntensity(app, bits_to_reduce)
            finalImg = app.img;

            if strcmp(app.WhichBitsToRemoveSwitch.Value, 'Rigth')
                finalImg = app.remove_right_most_bits(app.img, bits_to_reduce);
            else
                finalImg = app.remove_left_most_bits(app.img, bits_to_reduce);
            end

            finalImg = app.range_transformation(finalImg);
        end

        function output_img = remove_right_most_bits(~, img, bits_to_reduce)
            % output_img = floor(img / (2^bits_to_reduce)) * (2^bits_to_reduce);
            output_img = bitshift(bitshift(img, -bits_to_reduce), bits_to_reduce);
        end

        function output_img = remove_left_most_bits(~, img, bits_to_reduce)
            total_bits = 8;
        
            output_img = bitand(2^(total_bits - bits_to_reduce)-1, img);
        end

        function final_img = range_transformation(~, img)
            no_of_intensity_levels = 256;

            img = double(img);

            % Scaling
            temp = img - min(min(img));
            transformed_img = (no_of_intensity_levels - 1) * (temp/max(max(temp)));

            final_img = uint8(transformed_img);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.imgPath = 'face.tif';
%             app.imgPath = 'crowd.tif';
%             app.imgPath = 'cameraman.tif';
            app.imgPath = 'currency_note.tif';

            app.img = imread(app.imgPath);
            imshow(app.img, 'Parent', app.originalImgUIAxes);

            [app.originalImgUIAxes.XLim, app.interpolatedImgUIAxes.XLim] = deal([0 size(app.img, 2)]);
            [app.originalImgUIAxes.YLim, app.interpolatedImgUIAxes.YLim] = deal([0 size(app.img, 1)]);
            
            app.updateImg();
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            value = app.kSlider.Value;
            app.kSlider.Value = round(value);

            app.updateImg();
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            app.updateImg();
        end

        % Value changed function: WhichBitsToRemoveSwitch
        function WhichBitsToRemoveSwitchValueChanged(app, event)
            app.updateImg();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [1 8];
            app.kSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.MinorTicks = [];
            app.kSlider.Value = 8;
            app.NSlider_2Label = uilabel(app.UIFigure);
            app.NSlider_2Label.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0.1 1];
            app.NSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 1;
            app.originalImgUIAxes = uiaxes(app.UIFigure);
            title(app.originalImgUIAxes, 'Original image')
            xlabel(app.originalImgUIAxes, 'X')
            ylabel(app.originalImgUIAxes, 'Y')
            app.interpolatedImgUIAxes = uiaxes(app.UIFigure);
            title(app.interpolatedImgUIAxes, 'Interpolated image')
            xlabel(app.interpolatedImgUIAxes, 'X')
            ylabel(app.interpolatedImgUIAxes, 'Y')
            app.WhichBitsToRemoveSwitch = uiswitch(app.UIFigure, 'slider');
            app.WhichBitsToRemoveSwitch.Items = {'Left', 'Rigth'};
            app.WhichBitsToRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @WhichBitsToRemoveSwitchValueChanged, true);
            app.WhichBitsToRemoveSwitch.Value = 'Rigth';
            app.WhichbitstoremoveLabel = uilabel(app.UIFigure);
            app.WhichbitstoremoveLabel.Text = 'Which bits to remove?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playing_with_N_and_k

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end