classdef Log_and_Gemma_transformations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransformationsUIFigure    matlab.ui.Figure
        cSliderLabel               matlab.ui.control.Label
        cSlider                    matlab.ui.control.Slider
        TransformationPlot         matlab.ui.control.UIAxes
        OriginalImgUIAxes          matlab.ui.control.UIAxes
        TransformedImgUIAxes       matlab.ui.control.UIAxes
        ChoosetransformationButtonGroup  matlab.ui.container.ButtonGroup
        LogtransformationButton    matlab.ui.control.RadioButton
        GammatransformationButton  matlab.ui.control.RadioButton
        gemmaSliderLabel           matlab.ui.control.Label
        gemmaSlider                matlab.ui.control.Slider
    end

    properties (Access = private)
        imgPath
        img
        intensityLevels
    end

    methods (Access = private)

        function logTransformation = logTransformation(~, mat, c)
            logTransformation = c*log10(double(mat + 1));
        end
        function gemmaTransformation = gemmaTransformation(~, mat, c, gemma)
            gemmaTransformation = c*double(mat).^gemma;
        end

        function transformedMat = rangeTransformation(app, mat)
            minVal = min(min(mat));

            temp = mat - minVal;
            maxVal = max(max(temp));

            transformedMat = (app.intensityLevels-1)*((temp)./maxVal);
            transformedMat = uint8(transformedMat);
        end

        function updateThings(app)
            c = app.cSlider.Value;
            gemma = app.gemmaSlider.Value;
            graphX = linspace(0, app.intensityLevels);

            if app.LogtransformationButton.Value
                transformedImg = app.logTransformation(app.img, c);
                graphY = app.logTransformation(graphX, c);
            else
                transformedImg = app.gemmaTransformation(app.img, c, gemma);
                graphY = app.gemmaTransformation(graphX, c, gemma);
            end

            rescaledImg = app.rangeTransformation(transformedImg);
            rescaledGraphY = app.rangeTransformation(graphY);

            imshow(rescaledImg, 'Parent', app.TransformedImgUIAxes);
            plot(app.TransformationPlot, graphX, rescaledGraphY);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initializations
            app.intensityLevels = 256;
            app.imgPath = 'intensity transformation.tif';
            app.imgPath = 'backbone.tif';
            app.imgPath = 'aerial_view.tif';

            app.img = imread(app.imgPath);

            app.TransformationPlot.XTick = [0 app.intensityLevels/4 app.intensityLevels/2 3*app.intensityLevels/4 app.intensityLevels-1];
            app.TransformationPlot.YTick = [0 app.intensityLevels/4 app.intensityLevels/2 3*app.intensityLevels/4 app.intensityLevels-1];
            app.TransformationPlot.XTickLabel = { '0'; 'L/4'; 'L/2'; '3L/4'; 'L-1' };
            app.TransformationPlot.YTickLabel = { '0'; 'L/4'; 'L/2'; '3L/4'; 'L-1' };

            imshow(app.img, 'Parent', app.OriginalImgUIAxes);
            app.updateThings();
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            app.updateThings();
        end

        % Value changing function: cSlider
        function cSliderValueChanging(app, event)
%             app.updateThings();
        end

        % Value changed function: gemmaSlider
        function gemmaSliderChanged(app, event)
            if app.GammatransformationButton.Value
                app.updateThings();
            end
        end

        % Selection changed function: 
        % ChoosetransformationButtonGroup
        function transformation_changed(app, event)
            app.updateThings();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransformationsUIFigure = uifigure('Visible', 'off');
            app.TransformationsUIFigure.Name = 'Transformations';
            app.cSliderLabel = uilabel(app.TransformationsUIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.TransformationsUIFigure);
            app.cSlider.Limits = [0.0001 30];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.cSlider.Value = 0.0001;
            app.TransformationPlot = uiaxes(app.TransformationsUIFigure);
            title(app.TransformationPlot, 'Transformation plot')
            xlabel(app.TransformationPlot, 'Input intensity levels (r)')
            ylabel(app.TransformationPlot, 'Output intensity levels (s)')
            app.OriginalImgUIAxes = uiaxes(app.TransformationsUIFigure);
            title(app.OriginalImgUIAxes, 'Original img')
            xlabel(app.OriginalImgUIAxes, '')
            ylabel(app.OriginalImgUIAxes, '')
            app.TransformedImgUIAxes = uiaxes(app.TransformationsUIFigure);
            title(app.TransformedImgUIAxes, 'Transformed img')
            xlabel(app.TransformedImgUIAxes, '')
            ylabel(app.TransformedImgUIAxes, '')
            app.ChoosetransformationButtonGroup = uibuttongroup(app.TransformationsUIFigure);
            app.ChoosetransformationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @transformation_changed, true);
            app.ChoosetransformationButtonGroup.Title = 'Choose transformation';
            app.LogtransformationButton = uiradiobutton(app.ChoosetransformationButtonGroup);
            app.LogtransformationButton.Text = 'Log transformation';
            app.LogtransformationButton.Value = true;
            app.GammatransformationButton = uiradiobutton(app.ChoosetransformationButtonGroup);
            app.GammatransformationButton.Text = 'Gamma transformation';
            app.gemmaSliderLabel = uilabel(app.TransformationsUIFigure);
            app.gemmaSliderLabel.Text = 'gemma';
            app.gemmaSlider = uislider(app.TransformationsUIFigure);
            app.gemmaSlider.Limits = [0.001 10];
            app.gemmaSlider.ValueChangedFcn = createCallbackFcn(app, @gemmaSliderChanged, true);
            app.gemmaSlider.Value = 0.001;
            % Show the figure after all components are created
            app.TransformationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Log_and_Gemma_transformations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransformationsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransformationsUIFigure)
        end
    end
end