classdef spatial_filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Original_UIAxes          matlab.ui.control.UIAxes
        Manipulated_UIAxes       matlab.ui.control.UIAxes
        NSliderLabel             matlab.ui.control.Label
        NSlider                  matlab.ui.control.Slider
        MSliderLabel             matlab.ui.control.Label
        MSlider                  matlab.ui.control.Slider
        FilterSizeLabel          matlab.ui.control.Label
        FiltersButtonGroup       matlab.ui.container.ButtonGroup
        BoxButton                matlab.ui.control.RadioButton
        GaussianButton           matlab.ui.control.RadioButton
        Button3                  matlab.ui.control.RadioButton
        VarianceSliderLabel      matlab.ui.control.Label
        VarianceSlider           matlab.ui.control.Slider
        UseSquareFilterCheckBox  matlab.ui.control.CheckBox
        UseWhitePaddingCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        imgPath
        img
    end

    methods (Access = private)

        function filteredImg = updateImg(app)
            m = int32(app.MSlider.Value);
            n = int32(app.NSlider.Value);
            variance = app.VarianceSlider.Value;
            padSize = [m-1 n-1];
            
            if app.UseWhitePaddingCheckBox.Value == true
                padValue = 255;
            else
                padValue = 0;
            end

            if app.BoxButton.Value == true
                filter = ones([m n]);
            elseif app.GaussianButton.Value == true
                filter = app.getGaussianFilter([m n], variance);
            end

            paddedImg = padarray(app.img, double(padSize), padValue);
            filteredImg = app.applyFilter(paddedImg, filter);
            croppedImg = app.removePadding(filteredImg, [m n]);

            imshow(croppedImg, 'Parent', app.Manipulated_UIAxes);
        end

        function filteredImg = applyFilter(~, img, filter)
            s = size(filter, 1);
            t = size(filter, 2);

            xOffset = (s-1)/2;
            yOffset = (t-1)/2;

            filteredImg = zeros(size(img));
            sumOfFilterCoefficients = sum(filter, "all");

            for x = xOffset+1:size(img, 1)-xOffset
                for y = yOffset+1:size(img, 2)-yOffset
                    filterImgMul = double(img(x-xOffset:x+xOffset, y-yOffset:y+yOffset)) .* filter;
                    filteredImg(x, y) = sum(filterImgMul, 'all')/sumOfFilterCoefficients;
                end
            end

            filteredImg = uint8(filteredImg);
        end

        function filter = getGaussianFilter(~, filterSize, variance)
            filter = zeros(filterSize);
            half = (filterSize + 1) / 2;

            for s = 1:filterSize(1)
                for t = 1:filterSize(2)
                    filter(s, t) = exp(-sum(([s t]-half) .^ 2) / (2*variance^2));
                end
            end
        end

        function cropped = removePadding(~, img, padSize)
            m = padSize(1);
            n = padSize(2);

            cropped = img(m+1:end-m, n+1:end-n);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imgPath = "img.tif";
%             app.imgPath = "circuit board.tif";
            app.img = imread(app.imgPath);
            

            imgSize = size(app.img);
            
            [app.Original_UIAxes.XLim, app.Manipulated_UIAxes.XLim] = deal([0 imgSize(2)]);
            [app.Original_UIAxes.YLim, app.Manipulated_UIAxes.YLim] = deal([0 imgSize(1)]);
            
            imshow(app.img, 'Parent', app.Original_UIAxes);

            app.MSlider.Limits = [3 20];
            app.NSlider.Limits = [3 20];
            app.VarianceSlider.Enable = app.GaussianButton.Value;

            app.updateImg();
        end

        % Value changed function: MSlider, NSlider
        function kernelSizeChanged(app, event)
            % First rounding the values to nearest odd number
            if app.UseSquareFilterCheckBox.Value == true
                newSize = min(roundToNearestOddNumber(event.Value), min(app.MSlider.Limits(2), app.NSlider.Limits(2)));
                app.MSlider.Value = newSize;
                app.NSlider.Value = newSize;
            else
                m = app.MSlider.Value;
                n = app.NSlider.Value;

                newM = min(roundToNearestOddNumber(m), app.MSlider.Limits(2));
                newN = min(roundToNearestOddNumber(n), app.NSlider.Limits(2));

                app.MSlider.Value = newM;
                app.NSlider.Value = newN;
            end
            app.FilterSizeLabel.Text = "Filter size: " + app.MSlider.Value + " × " + app.NSlider.Value;

            % Now we'll apply the filter to img
            app.updateImg();

            function a = roundToNearestOddNumber(a)
                idx = mod(a, 2) < 1;
                a = floor(a);
                a(idx) = a(idx) + 1;
            end
        end

        % Selection changed function: FiltersButtonGroup
        function RadioBtnsChanged(app, event)
            app.VarianceSlider.Enable = app.GaussianButton.Value;

            app.updateImg();
        end

        % Value changed function: UseWhitePaddingCheckBox
        function UseWhitePaddingChkBxChanged(app, event)
            app.updateImg();
        end

        % Value changed function: UseSquareFilterCheckBox
        function UseSquareFilterChkBxChanged(app, event)
            if app.UseSquareFilterCheckBox.Value == true
                app.NSlider.Value = app.MSlider.Value;
            end
        end

        % Value changed function: VarianceSlider
        function VarienceValueChanged(app, event)
            app.updateImg();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original_UIAxes = uiaxes(app.UIFigure);
            title(app.Original_UIAxes, 'Original Image')
            xlabel(app.Original_UIAxes, '')
            ylabel(app.Original_UIAxes, '')
            app.Manipulated_UIAxes = uiaxes(app.UIFigure);
            title(app.Manipulated_UIAxes, 'Manipulatod image')
            xlabel(app.Manipulated_UIAxes, '')
            ylabel(app.Manipulated_UIAxes, '')
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @kernelSizeChanged, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 3;
            app.MSliderLabel = uilabel(app.UIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @kernelSizeChanged, true);
            app.MSlider.MinorTicks = [];
            app.MSlider.Value = 3;
            app.FilterSizeLabel = uilabel(app.UIFigure);
            app.FilterSizeLabel.Text = 'Filter size: 3 × 3';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioBtnsChanged, true);
            app.FiltersButtonGroup.Title = 'Filters';
            app.BoxButton = uiradiobutton(app.FiltersButtonGroup);
            app.BoxButton.Text = 'Box';
            app.BoxButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FiltersButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.Button3 = uiradiobutton(app.FiltersButtonGroup);
            app.Button3.Enable = 'off';
            app.Button3.Text = 'Button3';
            app.VarianceSliderLabel = uilabel(app.UIFigure);
            app.VarianceSliderLabel.Text = 'Variance';
            app.VarianceSlider = uislider(app.UIFigure);
            app.VarianceSlider.Limits = [0 5];
            app.VarianceSlider.ValueChangedFcn = createCallbackFcn(app, @VarienceValueChanged, true);
            app.UseSquareFilterCheckBox = uicheckbox(app.UIFigure);
            app.UseSquareFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSquareFilterChkBxChanged, true);
            app.UseSquareFilterCheckBox.Text = 'Use square filter';
            app.UseWhitePaddingCheckBox = uicheckbox(app.UIFigure);
            app.UseWhitePaddingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseWhitePaddingChkBxChanged, true);
            app.UseWhitePaddingCheckBox.Text = 'Use white padding';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spatial_filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end